classdef Mody_judge < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        CodeTab                     matlab.ui.container.Tab
        OutputDisplay               matlab.ui.control.TextArea
        SubmitButton                matlab.ui.control.Button
        RunCodeButton               matlab.ui.control.Button
        EditerPanel                 matlab.ui.container.Panel
        EditerTextArea              matlab.ui.control.TextArea
        EditerTextAreaLabel         matlab.ui.control.Label
        ResetEditor                 matlab.ui.control.Button
        SolutionPanel               matlab.ui.container.Panel
        SolutionTextArea            matlab.ui.control.TextArea
        SolutionTextAreaLabel       matlab.ui.control.Label
        ResetSolution               matlab.ui.control.Button
        Test_CaseTab                matlab.ui.container.Tab
        UITable                     matlab.ui.control.Table
        ModyJudgePanel              matlab.ui.container.Panel
        SelectProblemDropDown       matlab.ui.control.DropDown
        SelectProblemDropDownLabel  matlab.ui.control.Label
        HTML                        matlab.ui.control.HTML
        Problem_NameLabel           matlab.ui.control.Label
        ReloadButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        Problem_Set % Description

        Active_Problem
        Active_Problem_Index

        Default_Solution_Path
        Default_Editor_Path

        Default_Solution
        Default_Editor

        Solution
        Editor
    end
    
    methods (Access = private)
        
        function results = Load_Problem_Set(~,file)

            opts = spreadsheetImportOptions("NumVariables", 6);

            % Specify sheet and range
            opts.Sheet = "Sheet1";
            % opts.DataRange = "A2:F2";
            
            % Specify column names and types
            opts.VariableNames = ["Problem_ID", "Problem_Title", "Problem_Details", "Test_Case", "Default_Solution", "Default_Editor" , "Function_Name"];
            opts.VariableTypes = ["string", "string", "string", "string", "string", "string" , "string"];
            
            % Specify variable properties
            opts = setvaropts(opts, ["Problem_ID", "Problem_Title", "Problem_Details", "Test_Case", "Default_Solution", "Default_Editor" , "Function_Name"], "WhitespaceRule", "preserve");
            opts = setvaropts(opts, ["Problem_ID", "Problem_Title", "Problem_Details", "Test_Case", "Default_Solution", "Default_Editor" , "Function_Name"], "EmptyFieldRule", "auto");
            
            % Import the data
            ProblemSet = readtable(file, opts, "UseExcel", false);
            results = ProblemSet(2:end,:);

        end

        function reset_solution(app)
            app.SolutionTextArea.Value = app.Default_Solution ;
        end

        function reset_editor(app)
            app.EditerTextArea.Value = app.Default_Editor ;
        end

        function load_default(app)
            app.Default_Solution = fileread(app.get_absulute_path(app.Default_Solution_Path));
            app.Default_Editor = fileread(app.get_absulute_path(app.Default_Editor_Path));
        end

        function result = load_default_solution(app)
            app.SolutionTextArea.Value = app.Default_Solution ;
            result = app.Default_Solution ;
        end

        function result = load_default_editor(app)
            app.EditerTextArea.Value = app.Default_Editor ;
            result = app.Default_Editor ;
        end

        function load_active_problem(app)
            app.SelectProblemDropDownValueChanged ;
        end

        function load_path(app)
            app.Default_Solution_Path = app.Problem_Set.Default_Solution(app.Active_Problem_Index);
            app.Default_Editor_Path = app.Problem_Set.Default_Editor(app.Active_Problem_Index);
        end

        function load_problem_details(app)
            relative_path = app.Problem_Set.Problem_Details(app.Active_Problem_Index) ;
            app.HTML.HTMLSource = fileread( app.get_absulute_path(relative_path) );
        end

        function results = get_absulute_path(~ , path)
            results = fullfile( pwd , path ) ;
        end

        
        function results = Run_Code(app , code)
            % Create a cell array to store your commands
            % commands = {
            %     'disp("Hello, World!")',
            %     'x = 2 + 3',
            %     'y = sin(pi/4)'
            % };
            % commands = app.script2cell(code);

            commands = code ;

            % Initialize an empty character array to store the output
            output = '';

            % Loop through the commands and capture the output
            for i = 1:numel(commands)
                command = commands{i};
                output = strcat(output, evalc(command ));
            end

            % Display the captured output
            % disp(output);
            app.display_output(output) ;
            results = output ;

            % results = evalc(code) ;


        end

        function display_output(app , output)
            app.OutputDisplay.Value = output ;
        end
        
        function filewrite(~ , file_path , output)
            % Create or specify the file path where you want to save the string
            % file_path = 'output.txt';
            output = char(strjoin(output , '\n'));
            % Create or open the file for writing
            fileID = fopen(file_path, 'w'); % 'w' indicates write mode
            
            % Use fprintf to write the string to the file
            fprintf(fileID, '%s\n', output);
            
            % Close the file when done
            fclose(fileID);

        end
        
        function results = script2cell(app , script)
            lines = strsplit(script, '\n') ;
            
            % Convert the result to a cell array
            results = cellstr(lines);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load Problem Set
            app.Problem_Set = app.Load_Problem_Set("Problem_Set.xls");
            app.SelectProblemDropDown.Items = app.Problem_Set.Problem_ID ;
            
            app.load_active_problem ;

            app.load_path ;
            app.load_default ;

            app.load_problem_details ;
            
            app.Solution = app.load_default_solution ;
            app.Editor = app.load_default_editor ;


        end

        % Value changed function: SelectProblemDropDown
        function SelectProblemDropDownValueChanged(app, event)
            app.Active_Problem = app.SelectProblemDropDown.Value;
            app.Active_Problem_Index = find(app.Problem_Set.Problem_ID == app.Active_Problem) ;
            
        end

        % Button pushed function: ResetSolution
        function ResetSolutionButtonPushed(app, event)
            app.load_default_solution ;
        end

        % Button pushed function: ResetEditor
        function ResetEditorButtonPushed(app, event)
            app.load_default_editor ;
        end

        % Value changed function: EditerTextArea
        function EditerTextAreaValueChanged(app, event)
            app.Editor = app.EditerTextArea.Value;
            
        end

        % Button pushed function: RunCodeButton
        function RunCodeButtonPushed(app, event)
            % disp(app.Editor) ;
            % app.display_output(app.Editor)

            output_path = fullfile(pwd , app.Problem_Set.Function_Name(app.Active_Problem_Index));
            app.filewrite(output_path , app.Solution);
            
            % code = app.script2cell(app.Editor) ;
            class(app.Editor)
            Console_Output = app.Run_Code( app.Editor )
            % app.display_output(Console_Output) ;
            % output = app.Editor 
            % app.script2cell(output)
        end

        % Value changed function: SolutionTextArea
        function SolutionTextAreaValueChanged(app, event)
            app.Solution = app.SolutionTextArea.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ModyJudgePanel = uipanel(app.UIFigure);
            app.ModyJudgePanel.Title = 'Mody Judge';
            app.ReloadButton = uibutton(app.ModyJudgePanel, 'push');
            app.ReloadButton.Text = 'Reload';
            app.Problem_NameLabel = uilabel(app.ModyJudgePanel);
            app.Problem_NameLabel.Text = 'Problem_Name';
            app.HTML = uihtml(app.ModyJudgePanel);
            app.SelectProblemDropDownLabel = uilabel(app.ModyJudgePanel);
            app.SelectProblemDropDownLabel.Text = 'Select Problem';
            app.SelectProblemDropDown = uidropdown(app.ModyJudgePanel);
            app.SelectProblemDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectProblemDropDownValueChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CodeTab = uitab(app.TabGroup);
            app.CodeTab.Title = 'Code';
            app.SolutionPanel = uipanel(app.CodeTab);
            app.SolutionPanel.Title = 'Solution';
            app.ResetSolution = uibutton(app.SolutionPanel, 'push');
            app.ResetSolution.ButtonPushedFcn = createCallbackFcn(app, @ResetSolutionButtonPushed, true);
            app.ResetSolution.Text = 'Reset';
            app.SolutionTextAreaLabel = uilabel(app.SolutionPanel);
            app.SolutionTextAreaLabel.Text = 'Solution';
            app.SolutionTextArea = uitextarea(app.SolutionPanel);
            app.SolutionTextArea.ValueChangedFcn = createCallbackFcn(app, @SolutionTextAreaValueChanged, true);
            app.EditerPanel = uipanel(app.CodeTab);
            app.EditerPanel.Title = 'Editer';
            app.ResetEditor = uibutton(app.EditerPanel, 'push');
            app.ResetEditor.ButtonPushedFcn = createCallbackFcn(app, @ResetEditorButtonPushed, true);
            app.ResetEditor.Text = 'Reset';
            app.EditerTextAreaLabel = uilabel(app.EditerPanel);
            app.EditerTextAreaLabel.Text = 'Editer';
            app.EditerTextArea = uitextarea(app.EditerPanel);
            app.EditerTextArea.ValueChangedFcn = createCallbackFcn(app, @EditerTextAreaValueChanged, true);
            app.RunCodeButton = uibutton(app.CodeTab, 'push');
            app.RunCodeButton.ButtonPushedFcn = createCallbackFcn(app, @RunCodeButtonPushed, true);
            app.RunCodeButton.Text = 'Run Code';
            app.SubmitButton = uibutton(app.CodeTab, 'push');
            app.SubmitButton.Text = 'Submit';
            app.OutputDisplay = uitextarea(app.CodeTab);
            app.OutputDisplay.Editable = 'off';
            app.Test_CaseTab = uitab(app.TabGroup);
            app.Test_CaseTab.Title = 'Test_Case';
            app.UITable = uitable(app.Test_CaseTab);
            app.UITable.ColumnName = {'Test_Case'; 'Inputs'; 'Outputs'; 'Results'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mody_judge

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end